classdef constellation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SNREditField               matlab.ui.control.NumericEditField
        SNREditFieldLabel          matlab.ui.control.Label
        MEditField                 matlab.ui.control.NumericEditField
        MEditFieldLabel            matlab.ui.control.Label
        NosymbolsEditField         matlab.ui.control.NumericEditField
        NosymbolsEditFieldLabel    matlab.ui.control.Label
        CONSTELLATIONDIAGRAMLabel  matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        PlotButton                 matlab.ui.control.Button
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = public)
        snr % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function SliderValueChanged(app, event)
            snr=app.snr
            f = app.Slider.Value;
            snr=app.SNREditField.Value;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

data=[0 0 1 1 0 1 1 0 1 1 1 0 0 1 0 1 1 1 1 0]; % information

data_NZR=2*data-1; % Data Represented at NZR form for QPSK modulation

br=10.^6; %Let us transmission bit rate  1000000
f=br; % minimum carrier frequency
T=1/br; % bit duration
t=T/99:T/99:T; % Time vector for one bit information
y=[];
y_in=[];
d=zeros(1,length(data)/2);

for i=1:length(data)

    if (data(i)==0)
       d(i)=exp(j*pi/4);%45 degrees
       %y(i)=cos(2*pi*f*t);
       y2=cos(2*pi*f*t) ;% Quadrature component
       y_in=[y_in y2]; % inphase signal vector
    end
    if(data(i)==1)
        d(i)=exp(j*3*pi/4);
        %y(i)=cos(2*pi*f*t+pi);
        y2=cos(2*pi*f*t+ pi) ;% Quadrature component
        y_in=[y_in y2]; % inphase signal vector
    end

end
for i=1:length(d)
    d(i)=d(i);%+rand(1)/10;
end
Tx_sig=y; % transmitting signal after modulation
qpsk=d;
tt=T/99:T/99:(T*length(data));
plot(app.UIAxes,d,'o','linewidth',3), grid on;
title(app.UIAxes,' wave form for inphase component in QPSK modulation ');
xlabel(app.UIAxes,'time(sec)');
ylabel(app.UIAxes,' amplitude(volt0');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'BPSK', 'QAM', 'QPSK'};
            app.DropDown.Value = 'BPSK';
            app.CONSTELLATIONDIAGRAMLabel = uilabel(app.UIFigure);
            app.CONSTELLATIONDIAGRAMLabel.Text = 'CONSTELLATION DIAGRAM';
            app.NosymbolsEditFieldLabel = uilabel(app.UIFigure);
            app.NosymbolsEditFieldLabel.Text = 'No.symbols';
            app.NosymbolsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NosymbolsEditField.Placeholder = '0';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditFieldLabel = uilabel(app.UIFigure);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.SNREditField.Placeholder = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = constellation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end